classdef lidar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        apiButton      matlab.ui.control.Button
        get_obj        matlab.ui.control.Button
        obj_fileField  matlab.ui.control.EditField
        EditField      matlab.ui.control.EditField
        clearButton    matlab.ui.control.Button
        display        matlab.ui.control.Button
        getpos         matlab.ui.control.Button
        HTML           matlab.ui.control.HTML
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: display
        function displayPushed(app, event)
            filename = app.EditField.Value;
            load(filename);

            p = Position;
            lat = p.latitude;
            lon = p.longitude;
            spd = p.speed;

            nBins = 10;
            binSpacing = (max(spd) - min(spd))/nBins; 
            binRanges = min(spd):binSpacing:max(spd)-binSpacing; 
            
            % Add an inf to binRanges to enclose the values above the last bin.
            binRanges(end+1) = inf;
            
            % |histc| determines which bin each speed value falls into.
            [~, spdBins] = histc(spd, binRanges);
            
            lat = lat';
            lon = lon';
            spdBins = spdBins';
            
            % Create a geographical shape vector, which stores the line segments as
            % features.
            s = geoshape();
            
            for k = 1:nBins
                
                % Keep only the lat/lon values which match the current bin. Leave the 
                % rest as NaN, which are interpreted as breaks in the line segments.
                latValid = nan(1, length(lat));
                latValid(spdBins==k) = lat(spdBins==k);
                
                lonValid = nan(1, length(lon));
                lonValid(spdBins==k) = lon(spdBins==k);    
            
                % To make the path continuous despite being segmented into different
                % colors, the lat/lon values that occur after transitioning from the
                % current speed bin to another speed bin will need to be kept.
                transitions = [diff(spdBins) 0];
                insertionInd = find(spdBins==k & transitions~=0) + 1;
            
                % Preallocate space for and insert the extra lat/lon values.
                latSeg = zeros(1, length(latValid) + length(insertionInd));
                latSeg(insertionInd + (0:length(insertionInd)-1)) = lat(insertionInd);
                latSeg(~latSeg) = latValid;
                
                lonSeg = zeros(1, length(lonValid) + length(insertionInd));
                lonSeg(insertionInd + (0:length(insertionInd)-1)) = lon(insertionInd);
                lonSeg(~lonSeg) = lonValid;
            
                % Add the lat/lon segments to the geographic shape vector.
                s(k) = geoshape(latSeg, lonSeg);
                
            end
            
                 wm = webmap(app.UIfigure,'Open Street Map');      
                 % wm = webmap('Open Street Map');
                 mwLat = mean(lat);
                 mwLon = mean(lon);
                 name = 'Here';
%                 iconDir = fullfile(matlabroot,'toolbox','matlab','icons');
                 iconFilename = fullfile(iconDir, 'matlabicon.gif');
                 wmmarker(mwLat, mwLon, 'FeatureName', name, 'Icon', iconFilename);
                 colors = autumn(nBins);
                 wmline(s, 'Color', colors, 'Width', 5);
                 wmzoom(16);
%             app.HTML.HTMLSource = fullfile("mp4.html");
        end

        % Button pushed function: getpos
        function getposPushed(app, event)
            [file, path] = uigetfile();
            if isequal(file, 0)
                disp('User selected cancel')
            else
                disp(['User selected', fullfile(path, file)])
                app.EditField.Value = fullfile(path, file);
            end

        end

        % Button pushed function: get_obj
        function get_objPushed(app, event)
            [obj_file, path] = uigetfile('*', 'All files');
            if isequal(obj_file, 0)
                disp('User selected cancel')
            else
                disp(['User selected', fullfile(path, obj_file)])
                app.obj_fileField.Value = fullfile(path, obj_file);
            end

            v = VideoReader(obj_file);
            currAxes = app.UIAxes;
            while hasFrame(v)
                vidFrame = readFrame(v);
                image(vidFrame, 'Parent', currAxes);
                currAxes.Visible = 'off';
                pause(1/v.FrameRate);
            end
        end

        % Button pushed function: apiButton
        function apiButtonPushed(app, event)
            app.HTML.HTMLSource = fullfile("index.html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'3D_object'; ''})
            app.HTML = uihtml(app.UIFigure);
            app.getpos = uibutton(app.UIFigure, 'push');
            app.getpos.ButtonPushedFcn = createCallbackFcn(app, @getposPushed, true);
            app.getpos.Text = '位置情報取得';
            app.display = uibutton(app.UIFigure, 'push');
            app.display.ButtonPushedFcn = createCallbackFcn(app, @displayPushed, true);
            app.display.Text = '表示';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.Text = 'clear';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.obj_fileField = uieditfield(app.UIFigure, 'text');
            app.get_obj = uibutton(app.UIFigure, 'push');
            app.get_obj.ButtonPushedFcn = createCallbackFcn(app, @get_objPushed, true);
            app.get_obj.Text = 'オブジェクト読込';
            app.apiButton = uibutton(app.UIFigure, 'push');
            app.apiButton.ButtonPushedFcn = createCallbackFcn(app, @apiButtonPushed, true);
            app.apiButton.Text = 'api利用';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lidar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end